classdef TMVApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExitButton                    matlab.ui.control.Button
        TMVChecklistLabel             matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        OperatorNamesLabel            matlab.ui.control.Label
        NamesEditField                matlab.ui.control.EditField
        HavethesefixturesbeenqualifiedPanel  matlab.ui.container.Panel
        IQReportNumberLabel           matlab.ui.control.Label
        IQReportEditField             matlab.ui.control.EditField
        IQDropDown                    matlab.ui.control.DropDown
        HavethesefixturesequipmentegVisionSystemsbeencalibratedPanel  matlab.ui.container.Panel
        CalibrationNumberLabel        matlab.ui.control.Label
        CalibrationEditField          matlab.ui.control.EditField
        CalibratedDropDown            matlab.ui.control.DropDown
        Panel                         matlab.ui.container.Panel
        MSerialNumberInfo             matlab.ui.control.Button
        MNumberSerialNumberLabel      matlab.ui.control.Label
        MSerialEditField              matlab.ui.control.EditField
        IsthereaplannedchangetothistestmethodinfutureOPupdatesPanel  matlab.ui.container.Panel
        WhatistheplannedchangeLabel   matlab.ui.control.Label
        PlannedChangeDropDown         matlab.ui.control.DropDown
        PlannedChangeEditField        matlab.ui.control.EditField
        IdentifyyourTestMethodPanel   matlab.ui.container.Panel
        StepEditField                 matlab.ui.control.EditField
        StepLabel                     matlab.ui.control.Label
        RevLabel                      matlab.ui.control.Label
        OPNumberLabel                 matlab.ui.control.Label
        OPEditField                   matlab.ui.control.EditField
        RevEditField                  matlab.ui.control.EditField
        Workstep                      matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        InnerHHSNode                  matlab.ui.container.TreeNode
        VerificationNode              matlab.ui.container.TreeNode
        BlueLightNode                 matlab.ui.container.TreeNode
        PETNode                       matlab.ui.container.TreeNode
        DimensionalNode               matlab.ui.container.TreeNode
        PETOverhangNode               matlab.ui.container.TreeNode
        DistalTackNode                matlab.ui.container.TreeNode
        VisualNode                    matlab.ui.container.TreeNode
        PoorTackBondNode              matlab.ui.container.TreeNode
        TipAttachNode                 matlab.ui.container.TreeNode
        HHSE1DistanceNode             matlab.ui.container.TreeNode
        TipShapeNode                  matlab.ui.container.TreeNode
        XDimensionalNode              matlab.ui.container.TreeNode
        MBDistalTipNode               matlab.ui.container.TreeNode
        MarkerbandBubbleNode          matlab.ui.container.TreeNode
        XVisualNode                   matlab.ui.container.TreeNode
        TipLaminationNode             matlab.ui.container.TreeNode
        IMBondNode                    matlab.ui.container.TreeNode
        VerificationNode_2            matlab.ui.container.TreeNode
        CorrectBondaidIMBond          matlab.ui.container.TreeNode
        EXJBondNode                   matlab.ui.container.TreeNode
        VerificationNode_3            matlab.ui.container.TreeNode
        CorrectBondaidEXJBond         matlab.ui.container.TreeNode
        CorrectInnerMemberLengthNode  matlab.ui.container.TreeNode
        HoopingNode                   matlab.ui.container.TreeNode
        PatencyNode                   matlab.ui.container.TreeNode
        IMShaftNode                   matlab.ui.container.TreeNode
        VisualNode_2                  matlab.ui.container.TreeNode
        KinkedIMShaftNode             matlab.ui.container.TreeNode
        DistalSectionNode             matlab.ui.container.TreeNode
        VisualNode_3                  matlab.ui.container.TreeNode
        SurfaceImperfections          matlab.ui.container.TreeNode
        DimensionalNode_2             matlab.ui.container.TreeNode
        ElectrodeSpacingNode          matlab.ui.container.TreeNode
        FilletSeparationNode          matlab.ui.container.TreeNode
        FinalInspectNode              matlab.ui.container.TreeNode
        VisualImperfections           matlab.ui.container.TreeNode
    end

    properties (Access = private)
        Data % TMV Data - Stores the data read from the Excel file
    end

    methods (Access = public)
        function enableForm(app)
            % Enable all form elements for user interaction
            setFormFields(app, 'on');
        end

        function disableForm(app)
            % Disable all form elements to prevent user interaction
            setFormFields(app, 'off');
        end

        function setFormFields(app, state)
            % Set the enable state of form fields based on the provided state
            fields = {app.OPEditField, app.RevEditField, app.StepEditField, ...
                app.PlannedChangeDropDown, app.PlannedChangeEditField, ...
                app.MSerialEditField, app.CalibratedDropDown, ...
                app.CalibrationEditField, app.IQDropDown, ...
                app.IQReportEditField, app.NamesEditField};

            % Loop through each field and set its enable state
            for i = 1:length(fields)
                fields{i}.Enable = state; % Set the enable property of each field
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the name of the UI figure
            app.UIFigure.Name = 'Test Method Validation Checklist';

            % Read the table from the Excel file directly
            app.Data = readtable('TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1:Q14');

            % Set the variable names of the table to the values in the first row of the imported data
            app.Data.Properties.VariableNames = app.Data{1, :};
            app.Data(1, :) = []; % Remove the first row from the data

            % Initialize dropdown values to 'N/A'
            app.PlannedChangeDropDown.Value = 'N/A';
            app.CalibratedDropDown.Value = 'N/A';
            app.IQDropDown.Value = 'N/A';

            % Assign column indices to UserData properties for easy reference
            app.BlueLightNode.UserData = 2;
            app.PETOverhangNode.UserData = 3; 
            app.PoorTackBondNode.UserData = 4; 
            app.HHSE1DistanceNode.UserData = 5;
            app.TipLaminationNode.UserData = 6;
            app.MarkerbandBubbleNode.UserData = 7;
            app.MBDistalTipNode.UserData = 8;
            app.CorrectBondaidIMBond.UserData = 9;
            app.CorrectBondaidEXJBond.UserData = 10;
            app.CorrectInnerMemberLengthNode.UserData = 11;
            app.PatencyNode.UserData = 12;
            app.KinkedIMShaftNode.UserData = 13;
            app.SurfaceImperfections.UserData = 14;
            app.ElectrodeSpacingNode.UserData = 15;
            app.FilletSeparationNode.UserData = 16;
            app.VisualImperfections.UserData = 17;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNode = app.Tree.SelectedNodes;

            if isempty(selectedNode)
                disableForm(app); % Disable form if no node is selected
                return;
            end

            % Define worksteps
            Worksteps = {'Blue Light', 'PET Overhang', 'Poor Tack Bond', 'HHS – E1 Distance', 'MB-Distal Tip', 'Markerband Bubble', 'Tip Lamination', 'Correct Bondaid', 'Correct Bondaid', 'Correct Inner Member Length', 'Patency', 'Kinked IM Shaft', 'Surface Scratches & Fracture lines, Fillet Degradation', 'Electrode Spacing', 'Fillet Separation', 'Visual Imperfections'};

            % Check if the selected node is a workstep
            if ismember(selectedNode.Text, Worksteps)
                enableForm(app);
            else
                disableForm(app);
            end

            % Ensure text boxes effected by drop downs remain disabled
            app.PlannedChangeEditField.Enable = 'off'; 
            app.CalibrationEditField.Enable = 'off';
            app.IQReportEditField.Enable = 'off';

            % Update fields with workstep data if the selected node has no children
            if isempty(selectedNode.Children)
                % Use UserData to get the row index directly
                rowIndex = selectedNode.UserData; % Ensure UserData is set when creating nodes

                if ~isempty(app.Data) && any(rowIndex > 0) && all(rowIndex <= height(app.Data))
                    app.OPEditField.Value = app.Data{rowIndex, 'OP Number'};
                    app.RevTextArea.Value = app.Data{rowIndex, 'Rev'};
                    app.StepEditField.Value = app.Data{rowIndex, 'Step'};
                    app.PlannedChangeDropDown.Value = app.Data{rowIndex, 'Is there a planned change to the test method in future OP updates?'};
                    app.PlannedChangeEditField.Value = app.Data{rowIndex, 'What is the planned change?'};
                    app.MSerialNumberEditField.Value = app.Data{rowIndex, 'M/Serial Number'};
                    app.CalibratedDropDown.Value = app.Data{rowIndex, 'Have these equipment/fixtures been calibrated?'};
                    app.CalibratedEditField.Value = app.Data{rowIndex, 'What has been calibrated?'};
                    app.IQDropDown.Value = app.Data{rowIndex, 'Have these fixtures been qualified?'};
                    app.IQEditField.Value = app.Data{rowIndex, 'IQ Report Number'};
                    app.OperatorsEditField.Value = app.Data{rowIndex, 'Which operators have been trained?'};
                end
            end
        end

        % Value changed function: OPEditField
        function OPEditFieldValueChanged(app, event)
            % Get the input text from the OPEditField
            inputText = app.OPEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell array
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 1;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1'); % Save the data to Excel
        end

        % Value changed function: RevEditField
        function RevEditFieldValueChanged(app, event)
            % Get the input text from the Rev edit field
            inputText = app.RevEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 2;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1'); % Save the data to Excel
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            % Get the input text from the Step edit field
            inputText = app.StepEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 3;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1'); % Save the data to Excel
        end

        % Value changed function: PlannedChangeEditField
        function PlannedChangeEditFieldValueChanged(app, event)
            % Get the input text from the Planned Change edit field
            inputText = app.PlannedChangeEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 5;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1'); % Save the data to Excel
        end

        % Value changed function: PlannedChangeDropDown
        function PlannedChangeDropDownValueChanged(app, event)
            % Get the selected value from the Planned Change dropdown
            selectedValue = app.PlannedChangeDropDown.Value;

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column from UserData
            rowIndex = 4;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the selected value
            app.Data{rowIndex, columnIndex} = {selectedValue};

            % Write the updated table back to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1');

            % Enable or disable the text box based on the dropdown selection
            if strcmp(selectedValue, 'Y')
                app.PlannedChangeEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
            else
                app.PlannedChangeEditField.Enable = 'off'; % Disable the text box for other selections
            end
        end

        % Value changed function: MSerialEditField
        function MSerialEditFieldValueChanged(app, event)
            % Get the input text from the MSerial edit field
            inputText = app.MSerialEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 6;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText};

            % Write the updated table back to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1');
        end

        % Value changed function: CalibratedDropDown
        function CalibratedDropDownValueChanged(app, event)
            % Get the selected value from the calibrated dropdown
            selectedValue = app.CalibratedDropDown.Value;

            % Retrieve the currently selected node from the tree
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row and retrieve the column index from UserData
            rowIndex = 7;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the selected value
            app.Data{rowIndex, columnIndex} = {selectedValue};

            % Write the updated table back to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1');

            % Enable or disable the calibration edit field based on the dropdown selection
            if strcmp(selectedValue, 'Y')
                app.CalibrationEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
            else
                app.CalibrationEditField.Enable = 'off'; % Disable the text box for any other selection
            end
        end

        % Value changed function: CalibrationEditField
        function CalibrationEditFieldValueChanged(app, event)
            % Get the input text from the calibration edit field
            inputText = app.CalibrationEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 8;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText};

            % Write the updated table back to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1');
        end

        % Value changed function: IQDropDown
        function IQDropDownValueChanged(app, event)
            % Get the selected value from the dropdown
            selectedValue = app.IQDropDown.Value;

            % Retrieve the currently selected node from the tree
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 9;
            columnIndex = selectedNode.UserData; 

            % Update the data table with the selected value
            app.Data{rowIndex, columnIndex} = {selectedValue};

            % Write the updated table back to an Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1');

            % Enable or disable the text box based on the dropdown selection
            if strcmp(selectedValue, 'Y')
                app.IQReportEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
            else
                app.IQReportEditField.Enable = 'off'; % Disable the text box for any other selection
            end
        end

        % Value changed function: IQReportEditField
        function IQReportEditFieldValueChanged(app, event)
            % Retrieve the value from the IQReportEditField
            inputText = app.IQReportEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the currently node in the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and column name based on the selected node
            rowIndex = 10; 
            columnIndex = selectedNode.UserData;

            % Update the data table with the new input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table back to the Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1'); % Save changes to the Excel file
        end

        % Value changed function: NamesEditField
        function NamesEditFieldValueChanged(app, event)
            % Retrieve the value from the NamesEditField
            inputText = app.NamesEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the currently selected node in the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and column name based on the selected node
            rowIndex = 11; % Fixed row index for updating data
            columnIndex = selectedNode.UserData; % Get the column index from UserData of the selected node

            % Update the data table with the new input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table back to the Excel file
            writetable(app.Data, 'TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1'); % Save changes to the Excel file
        end

        % Button pushed function: MSerialNumberInfo
        function MSerialNumberInfoButtonPushed(app, event)
            app.MSerialNumberInfo.ButtonPushedFcn = @(src, event) showMoreInfo(app);
        end

        function showMoreInfo(app)
            % Display a UI alert with more information
            uialert(app.UIFigure, 'Identify the fixturing/equipment/tooling/software associated with the test method, e.g. templates, solder pallets, vision systems, tweezers.', 'More Info', ...
                'Icon', 'none'); % Set icon to none to avoid warning symbol
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app); % Closes the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InnerHHSNode = uitreenode(app.Tree);
            app.InnerHHSNode.Text = 'Inner-HHS';
            app.VerificationNode = uitreenode(app.InnerHHSNode);
            app.VerificationNode.Text = 'Verification';
            app.BlueLightNode = uitreenode(app.VerificationNode);
            app.BlueLightNode.Text = 'Blue Light';
            app.PETNode = uitreenode(app.Tree);
            app.PETNode.Text = 'PET';
            app.DimensionalNode = uitreenode(app.PETNode);
            app.DimensionalNode.Text = 'Dimensional';
            app.PETOverhangNode = uitreenode(app.DimensionalNode);
            app.PETOverhangNode.Text = 'PET Overhang';
            app.DistalTackNode = uitreenode(app.Tree);
            app.DistalTackNode.Text = 'Distal Tack';
            app.VisualNode = uitreenode(app.DistalTackNode);
            app.VisualNode.Text = 'Visual';
            app.PoorTackBondNode = uitreenode(app.VisualNode);
            app.PoorTackBondNode.Text = 'Poor Tack Bond';
            app.TipAttachNode = uitreenode(app.Tree);
            app.TipAttachNode.Text = 'Tip Attach';
            app.HHSE1DistanceNode = uitreenode(app.TipAttachNode);
            app.HHSE1DistanceNode.Text = 'HHS – E1 Distance';
            app.TipShapeNode = uitreenode(app.Tree);
            app.TipShapeNode.Text = 'Tip Shape';
            app.XDimensionalNode = uitreenode(app.TipShapeNode);
            app.XDimensionalNode.Text = '(25X) (Dimensional)';
            app.MBDistalTipNode = uitreenode(app.XDimensionalNode);
            app.MBDistalTipNode.Text = 'MB-Distal Tip';
            app.MarkerbandBubbleNode = uitreenode(app.XDimensionalNode);
            app.MarkerbandBubbleNode.Text = 'Markerband Bubble';
            app.XVisualNode = uitreenode(app.TipShapeNode);
            app.XVisualNode.Text = '(60X) (Visual)';
            app.TipLaminationNode = uitreenode(app.XVisualNode);
            app.TipLaminationNode.Text = 'Tip Lamination';
            app.IMBondNode = uitreenode(app.Tree);
            app.IMBondNode.Text = 'IM Bond';
            app.VerificationNode_2 = uitreenode(app.IMBondNode);
            app.VerificationNode_2.Text = 'Verification';
            app.CorrectBondaidIMBond = uitreenode(app.VerificationNode_2);
            app.CorrectBondaidIMBond.Text = 'Correct Bondaid';
            app.EXJBondNode = uitreenode(app.Tree);
            app.EXJBondNode.Text = 'EXJ Bond';
            app.VerificationNode_3 = uitreenode(app.EXJBondNode);
            app.VerificationNode_3.Text = 'Verification';
            app.CorrectBondaidEXJBond = uitreenode(app.VerificationNode_3);
            app.CorrectBondaidEXJBond.Text = 'Correct Bondaid';
            app.CorrectInnerMemberLengthNode = uitreenode(app.VerificationNode_3);
            app.CorrectInnerMemberLengthNode.Text = 'Correct Inner Member Length';
            app.HoopingNode = uitreenode(app.Tree);
            app.HoopingNode.Text = 'Hooping';
            app.PatencyNode = uitreenode(app.HoopingNode);
            app.PatencyNode.Text = 'Patency';
            app.IMShaftNode = uitreenode(app.HoopingNode);
            app.IMShaftNode.Text = 'IM Shaft';
            app.VisualNode_2 = uitreenode(app.IMShaftNode);
            app.VisualNode_2.Text = 'Visual';
            app.KinkedIMShaftNode = uitreenode(app.VisualNode_2);
            app.KinkedIMShaftNode.Text = 'Kinked IM Shaft';
            app.DistalSectionNode = uitreenode(app.HoopingNode);
            app.DistalSectionNode.Text = 'Distal Section';
            app.VisualNode_3 = uitreenode(app.DistalSectionNode);
            app.VisualNode_3.Text = 'Visual';
            app.SurfaceImperfections = uitreenode(app.VisualNode_3);
            app.SurfaceImperfections.Text = 'Surface Scratches & Fracture lines, Fillet Degradation';
            app.DimensionalNode_2 = uitreenode(app.DistalSectionNode);
            app.DimensionalNode_2.Text = 'Dimensional';
            app.ElectrodeSpacingNode = uitreenode(app.DimensionalNode_2);
            app.ElectrodeSpacingNode.Text = 'Electrode Spacing';
            app.FilletSeparationNode = uitreenode(app.DimensionalNode_2);
            app.FilletSeparationNode.Text = 'Fillet Separation';
            app.FinalInspectNode = uitreenode(app.Tree);
            app.FinalInspectNode.Text = 'Final Inspect';
            app.VisualImperfections = uitreenode(app.FinalInspectNode);
            app.VisualImperfections.Text = 'Visual Imperfections';
            app.Workstep = uilabel(app.UIFigure);
            app.Workstep.Text = 'Workstep';
            app.IdentifyyourTestMethodPanel = uipanel(app.UIFigure);
            app.IdentifyyourTestMethodPanel.Title = 'Identify your Test Method';
            app.RevEditField = uieditfield(app.IdentifyyourTestMethodPanel, 'text');
            app.RevEditField.ValueChangedFcn = createCallbackFcn(app, @RevEditFieldValueChanged, true);
            app.RevEditField.Enable = 'off';
            app.OPEditField = uieditfield(app.IdentifyyourTestMethodPanel, 'text');
            app.OPEditField.ValueChangedFcn = createCallbackFcn(app, @OPEditFieldValueChanged, true);
            app.OPEditField.Enable = 'off';
            app.OPNumberLabel = uilabel(app.IdentifyyourTestMethodPanel);
            app.OPNumberLabel.Text = 'OP Number';
            app.RevLabel = uilabel(app.IdentifyyourTestMethodPanel);
            app.RevLabel.Text = 'Rev';
            app.StepLabel = uilabel(app.IdentifyyourTestMethodPanel);
            app.StepLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.IdentifyyourTestMethodPanel, 'text');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Enable = 'off';
            app.IsthereaplannedchangetothistestmethodinfutureOPupdatesPanel = uipanel(app.UIFigure);
            app.IsthereaplannedchangetothistestmethodinfutureOPupdatesPanel.Title = 'Is there a planned change to this test method in future OP updates?';
            app.PlannedChangeEditField = uieditfield(app.IsthereaplannedchangetothistestmethodinfutureOPupdatesPanel, 'text');
            app.PlannedChangeEditField.ValueChangedFcn = createCallbackFcn(app, @PlannedChangeEditFieldValueChanged, true);
            app.PlannedChangeEditField.Enable = 'off';
            app.PlannedChangeDropDown = uidropdown(app.IsthereaplannedchangetothistestmethodinfutureOPupdatesPanel);
            app.PlannedChangeDropDown.Items = {'Y', 'N', 'N/A'};
            app.PlannedChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlannedChangeDropDownValueChanged, true);
            app.PlannedChangeDropDown.Enable = 'off';
            app.PlannedChangeDropDown.Value = 'Y';
            app.WhatistheplannedchangeLabel = uilabel(app.IsthereaplannedchangetothistestmethodinfutureOPupdatesPanel);
            app.WhatistheplannedchangeLabel.Text = 'What is the planned change?';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Identify the fixturing/equipment/tooling/software associated with the Test Method';
            app.MSerialEditField = uieditfield(app.Panel, 'text');
            app.MSerialEditField.ValueChangedFcn = createCallbackFcn(app, @MSerialEditFieldValueChanged, true);
            app.MSerialEditField.Enable = 'off';
            app.MNumberSerialNumberLabel = uilabel(app.Panel);
            app.MNumberSerialNumberLabel.Text = 'M Number/Serial Number';
            app.MSerialNumberInfo = uibutton(app.Panel, 'push');
            app.MSerialNumberInfo.ButtonPushedFcn = createCallbackFcn(app, @MSerialNumberInfoButtonPushed, true);
            app.MSerialNumberInfo.Text = 'i';
            app.HavethesefixturesequipmentegVisionSystemsbeencalibratedPanel = uipanel(app.UIFigure);
            app.HavethesefixturesequipmentegVisionSystemsbeencalibratedPanel.Title = 'Have these fixtures/equipment (e.g. Vision Systems) been calibrated?';
            app.CalibratedDropDown = uidropdown(app.HavethesefixturesequipmentegVisionSystemsbeencalibratedPanel);
            app.CalibratedDropDown.Items = {'Y', 'N', 'N/A'};
            app.CalibratedDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratedDropDownValueChanged, true);
            app.CalibratedDropDown.Enable = 'off';
            app.CalibratedDropDown.Value = 'Y';
            app.CalibrationEditField = uieditfield(app.HavethesefixturesequipmentegVisionSystemsbeencalibratedPanel, 'text');
            app.CalibrationEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationEditFieldValueChanged, true);
            app.CalibrationEditField.Enable = 'off';
            app.CalibrationNumberLabel = uilabel(app.HavethesefixturesequipmentegVisionSystemsbeencalibratedPanel);
            app.CalibrationNumberLabel.Text = 'Calibration Number';
            app.HavethesefixturesbeenqualifiedPanel = uipanel(app.UIFigure);
            app.HavethesefixturesbeenqualifiedPanel.Title = 'Have these fixtures been qualified?';
            app.IQDropDown = uidropdown(app.HavethesefixturesbeenqualifiedPanel);
            app.IQDropDown.Items = {'Y', 'N', 'N/A'};
            app.IQDropDown.ValueChangedFcn = createCallbackFcn(app, @IQDropDownValueChanged, true);
            app.IQDropDown.Enable = 'off';
            app.IQDropDown.Value = 'Y';
            app.IQReportEditField = uieditfield(app.HavethesefixturesbeenqualifiedPanel, 'text');
            app.IQReportEditField.ValueChangedFcn = createCallbackFcn(app, @IQReportEditFieldValueChanged, true);
            app.IQReportEditField.Enable = 'off';
            app.IQReportNumberLabel = uilabel(app.HavethesefixturesbeenqualifiedPanel);
            app.IQReportNumberLabel.Text = 'IQ Report Number';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Which Operators have been trained to this process step?';
            app.NamesEditField = uieditfield(app.Panel_2, 'text');
            app.NamesEditField.ValueChangedFcn = createCallbackFcn(app, @NamesEditFieldValueChanged, true);
            app.NamesEditField.Enable = 'off';
            app.OperatorNamesLabel = uilabel(app.Panel_2);
            app.OperatorNamesLabel.Text = 'Operator Names';
            app.TMVChecklistLabel = uilabel(app.UIFigure);
            app.TMVChecklistLabel.Text = 'TMV Checklist';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMVApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end